classdef Epidemic_Prediction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        ClearButton                   matlab.ui.control.Button
        PredictButton                 matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        LimitDropDown                 matlab.ui.control.DropDown
        Min                           matlab.ui.control.NumericEditField
        Max                           matlab.ui.control.NumericEditField
        ApplyButton                   matlab.ui.control.Button
        TypeDropDown                  matlab.ui.control.DropDown
        I0EditFieldLabel              matlab.ui.control.Label
        I0EditField                   matlab.ui.control.NumericEditField
        InitialnumberofinfectioushumansLabel  matlab.ui.control.Label
        InitialstatusPreventionfortheinfectiousdiseasesLabel  matlab.ui.control.Label
        InitialnumberofsuscepticalhumansLabel  matlab.ui.control.Label
        S0EditFieldLabel              matlab.ui.control.Label
        S0EditField                   matlab.ui.control.NumericEditField
        NumberofcontactsperunittimeLabel  matlab.ui.control.Label
        rEditFieldLabel               matlab.ui.control.Label
        rEditField                    matlab.ui.control.NumericEditField
        pEditFieldLabel               matlab.ui.control.Label
        pEditField                    matlab.ui.control.NumericEditField
        E0EditFieldLabel              matlab.ui.control.Label
        E0EditField                   matlab.ui.control.NumericEditField
        R0EditFieldLabel              matlab.ui.control.Label
        R0EditField                   matlab.ui.control.NumericEditField
        TypicalexampleLabel           matlab.ui.control.Label
        InitialnumberofexposedhumansLabel  matlab.ui.control.Label
        PercapitaprotectionrateLabel  matlab.ui.control.Label
        InitialnumberofrecoveredhumansLabel  matlab.ui.control.Label
        DropDown                      matlab.ui.control.DropDown
        CharacteristicsoftheinfectiousdiseasesLabel  matlab.ui.control.Label
        betaEditFieldLabel            matlab.ui.control.Label
        betaEditField                 matlab.ui.control.NumericEditField
        ProbabilityofdiseasetransmissionpercontactLabel  matlab.ui.control.Label
        epsilonEditFieldLabel         matlab.ui.control.Label
        epsilonEditField              matlab.ui.control.NumericEditField
        TypicalexampleLabel_2         matlab.ui.control.Label
        DropDown_2                    matlab.ui.control.DropDown
        gammaEditFieldLabel           matlab.ui.control.Label
        gammaEditField                matlab.ui.control.NumericEditField
        PercapitarecoveryrateLabel    matlab.ui.control.Label
        muEditFieldLabel              matlab.ui.control.Label
        muEditField                   matlab.ui.control.NumericEditField
        PercapitaremovalrateLabel     matlab.ui.control.Label
        NoteThingsabovearenotnumberstheyarefathersmotherschildrenLabel  matlab.ui.control.Label
        SOFTWAREBYLETIANTAOLabel      matlab.ui.control.Label
        PercapitarateofprogressiontoinfectiousstateLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            YLim=app.UIAxes.YLim;
            YTick=app.UIAxes.YTick;
            YTickLabel=app.UIAxes.YTickLabel;
            XLim=app.UIAxes.XLim;
            XTick=app.UIAxes.XTick;
            XTickLabel=app.UIAxes.XTickLabel;
            cla(app.UIAxes,"reset");
            app.UIAxes.Position = [22 445 954 350];
            title(app.UIAxes, 'Epidemic Prediction based on SEIR model')
            xlabel(app.UIAxes, 'Time/Day')
            ylabel(app.UIAxes, 'Number of people')
            app.UIAxes.PlotBoxAspectRatio = [2.58490566037736 1 1];
            app.UIAxes.FontSize = 16;
            app.UIAxes.XLim = XLim;
            app.UIAxes.YLim = YLim;
            app.UIAxes.XTick = XTick;
            app.UIAxes.XTickLabel = XTickLabel;
            app.UIAxes.YTick = YTick;
            app.UIAxes.YTickLabel = YTickLabel;
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.UIAxes.TitleFontWeight = 'bold';    
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if strcmp(app.LimitDropDown.Value,'Ymin/Ymax')
                app.UIAxes.YLim=[app.Min.Value,app.Max.Value];
                app.UIAxes.YTick = linspace(app.Min.Value,app.Max.Value,11);
                app.UIAxes.YTickLabel = num2str(linspace(app.Min.Value,app.Max.Value,11)');
            elseif strcmp(app.LimitDropDown.Value,'Xmin/Xmax')
                app.UIAxes.XLim=[app.Min.Value,app.Max.Value];
                app.UIAxes.XTick = linspace(app.Min.Value,app.Max.Value,9);
                app.UIAxes.XTickLabel = num2str(linspace(app.Min.Value,app.Max.Value,9)');
            end
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            %设定参数和初始值导入base
            assignin('base','S0',app.S0EditField.Value);
            assignin('base','E0',app.E0EditField.Value);
            assignin('base','I0',app.I0EditField.Value);
            assignin('base','R0',app.R0EditField.Value);
            assignin('base','r',app.rEditField.Value);
            assignin('base','p',app.pEditField.Value);
            assignin('base','beta',app.betaEditField.Value);
            assignin('base','epsilon',app.epsilonEditField.Value);
            assignin('base','gamma',app.gammaEditField.Value);
            assignin('base','mu',app.muEditField.Value);
            
            %调用模型
            SimOut=sim('SEIR_model');
            assignin('base','SimOut',SimOut) %局部工作区Pbat赋值给基础工作区Pbat1

            %画图
            if strcmp(app.TypeDropDown.Value,'Susceptical')
                plot(app.UIAxes,SimOut.tout,SimOut.yout{1}.Values.Data);
                ylabel(app.UIAxes, 'Number of susceptical people');
            elseif strcmp(app.TypeDropDown.Value,'Exposed')
                plot(app.UIAxes,SimOut.tout,SimOut.yout{2}.Values.Data);
                ylabel(app.UIAxes, 'Number of exposed people');
            elseif strcmp(app.TypeDropDown.Value,'Infectious')
                plot(app.UIAxes,SimOut.tout,SimOut.yout{3}.Values.Data);
                ylabel(app.UIAxes, 'Number of infectious people');
            elseif strcmp(app.TypeDropDown.Value,'Recovered')
                plot(app.UIAxes,SimOut.tout,SimOut.yout{4}.Values.Data);
                ylabel(app.UIAxes, 'Number of recovered people');
            elseif strcmp(app.TypeDropDown.Value,'Dead')
                plot(app.UIAxes,SimOut.tout,SimOut.yout{5}.Values.Data);
                ylabel(app.UIAxes, 'Number of dead people');
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes,'reset');
            title(app.UIAxes, 'Epidemic Prediction based on SEIR model')
            xlabel(app.UIAxes, 'Time/Day')
            ylabel(app.UIAxes, 'Number of people')
            app.UIAxes.PlotBoxAspectRatio = [2.58490566037736 1 1];
            app.UIAxes.FontSize = 16;
            app.UIAxes.XLim = [0 80];
            app.UIAxes.YLim = [0 1000];
            app.UIAxes.XTick = [0 10 20 30 40 50 60 70 80];
            app.UIAxes.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'};
            app.UIAxes.YTick = [0 200 400 600 800 1000];
            app.UIAxes.YTickLabel = {'0'; '200'; '400'; '600'; '800'; '1000'};
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.UIAxes.TitleFontWeight = 'bold';
            app.UIAxes.Position = [22 445 954 350];
            
            %默认参数
            app.S0EditField.Value=999;
            app.E0EditField.Value=0;
            app.I0EditField.Value=1;
            app.R0EditField.Value=0;
            app.rEditField.Value=365/3;
            app.pEditField.Value=0;
            app.betaEditField.Value=0.005;
            app.epsilonEditField.Value=0.1;
            app.gammaEditField.Value=0.1;
            app.muEditField.Value=0.05;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if strcmp(value,'Herd immunity')
                app.rEditField.Value=365/3;
                app.pEditField.Value=0;
            elseif strcmp(value,'100% quarantine')
                app.rEditField.Value=0;
            elseif strcmp(value,'100% protection')
                app.pEditField.Value=1;
            elseif strcmp(value,'100% vaccine')
                app.E0EditField.Value=app.E0EditField.Value+app.S0EditField.Value;
                app.S0EditField.Value=0;
            end
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            if strcmp(value,'COVID-19')
                app.betaEditField.Value=0.001644;
                app.epsilonEditField.Value=0.4;
                app.gammaEditField.Value=0.1;
                app.muEditField.Value=0.005;
            elseif strcmp(value,'Mers')
                app.betaEditField.Value=0.0005753;
                app.epsilonEditField.Value=0.4;
                app.gammaEditField.Value=0.1;
                app.muEditField.Value=0.05;
            elseif strcmp(value,'Ebola')
                app.betaEditField.Value=0.001397;
                app.epsilonEditField.Value=0.5;
                app.gammaEditField.Value=0.1;
                app.muEditField.Value=0.8;
            elseif strcmp(value,'Small pox')
                app.betaEditField.Value=0.005753;
                app.epsilonEditField.Value=0.3;
                app.gammaEditField.Value=0.1;
                app.muEditField.Value=0.05;
            end
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value;
            SimOut=evalin('base','SimOut');
            if strcmp(value,'Susceptical')
                plot(app.UIAxes,SimOut.tout,SimOut.yout{1}.Values.Data);
                ylabel(app.UIAxes, 'Number of susceptical people');
            elseif strcmp(value,'Exposed')
                plot(app.UIAxes,SimOut.tout,SimOut.yout{2}.Values.Data);
                ylabel(app.UIAxes, 'Number of exposed people');
            elseif strcmp(value,'Infectious')
                plot(app.UIAxes,SimOut.tout,SimOut.yout{3}.Values.Data);
                ylabel(app.UIAxes, 'Number of infectious people');
            elseif strcmp(value,'Recovered')
                plot(app.UIAxes,SimOut.tout,SimOut.yout{4}.Values.Data);
                ylabel(app.UIAxes, 'Number of recovered people');
            elseif strcmp(value,'Dead')
                plot(app.UIAxes,SimOut.tout,SimOut.yout{5}.Values.Data);
                ylabel(app.UIAxes, 'Number of dead people');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Epidemic Prediction based on SEIR model')
            xlabel(app.UIAxes, 'Time/Day')
            ylabel(app.UIAxes, 'Number of people')
            app.UIAxes.XLim = [0 80];
            app.UIAxes.YLim = [0 1000];
            app.UIAxes.XTick = [0 10 20 30 40 50 60 70 80];
            app.UIAxes.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'};
            app.UIAxes.YTick = [0 200 400 600 800 1000];
            app.UIAxes.YTickLabel = {'0'; '200'; '400'; '600'; '800'; '1000'};
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.LimitDropDown = uidropdown(app.UIFigure);
            app.LimitDropDown.Items = {'Ymin/Ymax', 'Xmin/Xmax'};
            app.LimitDropDown.Value = 'Ymin/Ymax';
            app.Min = uieditfield(app.UIFigure, 'numeric');
            app.Max = uieditfield(app.UIFigure, 'numeric');
            app.Max.Value = 1000;
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.TypeDropDown = uidropdown(app.UIFigure);
            app.TypeDropDown.Items = {'Susceptical', 'Exposed', 'Infectious', 'Recovered', 'Dead'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'Susceptical';
            app.I0EditFieldLabel = uilabel(app.UIFigure);
            app.I0EditFieldLabel.Text = 'I0';
            app.I0EditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialnumberofinfectioushumansLabel = uilabel(app.UIFigure);
            app.InitialnumberofinfectioushumansLabel.Text = 'Initial number of infectious humans';
            app.InitialstatusPreventionfortheinfectiousdiseasesLabel = uilabel(app.UIFigure);
            app.InitialstatusPreventionfortheinfectiousdiseasesLabel.Text = 'Initial status & Prevention for the infectious diseases';
            app.InitialnumberofsuscepticalhumansLabel = uilabel(app.UIFigure);
            app.InitialnumberofsuscepticalhumansLabel.Text = 'Initial number of susceptical humans';
            app.S0EditFieldLabel = uilabel(app.UIFigure);
            app.S0EditFieldLabel.Text = 'S0';
            app.S0EditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofcontactsperunittimeLabel = uilabel(app.UIFigure);
            app.NumberofcontactsperunittimeLabel.Text = 'Number of contacts per unit time';
            app.rEditFieldLabel = uilabel(app.UIFigure);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.UIFigure, 'numeric');
            app.pEditFieldLabel = uilabel(app.UIFigure);
            app.pEditFieldLabel.Text = 'p';
            app.pEditField = uieditfield(app.UIFigure, 'numeric');
            app.E0EditFieldLabel = uilabel(app.UIFigure);
            app.E0EditFieldLabel.Text = 'E0';
            app.E0EditField = uieditfield(app.UIFigure, 'numeric');
            app.R0EditFieldLabel = uilabel(app.UIFigure);
            app.R0EditFieldLabel.Text = 'R0';
            app.R0EditField = uieditfield(app.UIFigure, 'numeric');
            app.TypicalexampleLabel = uilabel(app.UIFigure);
            app.TypicalexampleLabel.Text = 'Typical example';
            app.InitialnumberofexposedhumansLabel = uilabel(app.UIFigure);
            app.InitialnumberofexposedhumansLabel.Text = 'Initial number of exposed humans';
            app.PercapitaprotectionrateLabel = uilabel(app.UIFigure);
            app.PercapitaprotectionrateLabel.Text = 'Per-capita protection rate';
            app.InitialnumberofrecoveredhumansLabel = uilabel(app.UIFigure);
            app.InitialnumberofrecoveredhumansLabel.Text = 'Initial number of recovered humans';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Herd immunity', '100% quarantine', '100% protection', '100% vaccine'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Herd immunity';
            app.CharacteristicsoftheinfectiousdiseasesLabel = uilabel(app.UIFigure);
            app.CharacteristicsoftheinfectiousdiseasesLabel.Text = 'Characteristics of the infectious diseases';
            app.betaEditFieldLabel = uilabel(app.UIFigure);
            app.betaEditFieldLabel.Text = 'beta';
            app.betaEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProbabilityofdiseasetransmissionpercontactLabel = uilabel(app.UIFigure);
            app.ProbabilityofdiseasetransmissionpercontactLabel.Text = 'Probability of disease transmission per contact';
            app.epsilonEditFieldLabel = uilabel(app.UIFigure);
            app.epsilonEditFieldLabel.Text = 'epsilon';
            app.epsilonEditField = uieditfield(app.UIFigure, 'numeric');
            app.TypicalexampleLabel_2 = uilabel(app.UIFigure);
            app.TypicalexampleLabel_2.Text = 'Typical example';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.Items = {'COVID-19', 'Mers', 'Ebola', 'Small pox'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 'COVID-19';
            app.gammaEditFieldLabel = uilabel(app.UIFigure);
            app.gammaEditFieldLabel.Text = 'gamma';
            app.gammaEditField = uieditfield(app.UIFigure, 'numeric');
            app.PercapitarecoveryrateLabel = uilabel(app.UIFigure);
            app.PercapitarecoveryrateLabel.Text = 'Per-capita recovery rate';
            app.muEditFieldLabel = uilabel(app.UIFigure);
            app.muEditFieldLabel.Text = 'mu';
            app.muEditField = uieditfield(app.UIFigure, 'numeric');
            app.PercapitaremovalrateLabel = uilabel(app.UIFigure);
            app.PercapitaremovalrateLabel.Text = 'Per-capita removal rate';
            app.NoteThingsabovearenotnumberstheyarefathersmotherschildrenLabel = uilabel(app.UIFigure);
            app.NoteThingsabovearenotnumberstheyarefathersmotherschildrenLabel.Text = 'Note:Things above are not numbers,they are fathers,mothers,children…';
            app.SOFTWAREBYLETIANTAOLabel = uilabel(app.UIFigure);
            app.SOFTWAREBYLETIANTAOLabel.Text = 'SOFTWARE BY LETIAN TAO';
            app.PercapitarateofprogressiontoinfectiousstateLabel = uilabel(app.UIFigure);
            app.PercapitarateofprogressiontoinfectiousstateLabel.Text = 'Per-capita rate of progression to infectious state';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Epidemic_Prediction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end